classdef Cost_estimation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cost_Estimation             matlab.ui.Figure
        CostEstimationinHybridAdditiveManufacturingHAMLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        LMDTab                      matlab.ui.container.Tab
        EstimatedtimeforAdditiveManufacturingPanel  matlab.ui.container.Panel
        PreprocesssingtimeminLabel  matlab.ui.control.Label
        PRPT                        matlab.ui.control.NumericEditField
        ProcessingtimeminLabel      matlab.ui.control.Label
        PT                          matlab.ui.control.NumericEditField
        PostprocessingtimeminLabel  matlab.ui.control.Label
        POPT                        matlab.ui.control.NumericEditField
        TotaltimeinAMminLabel       matlab.ui.control.Label
        TTA                         matlab.ui.control.NumericEditField
        PreprocesssingtimehrLabel   matlab.ui.control.Label
        PRPT_2                      matlab.ui.control.NumericEditField
        ProcessingtimehrLabel       matlab.ui.control.Label
        PT_2                        matlab.ui.control.NumericEditField
        PostprocessingtimehrLabel   matlab.ui.control.Label
        POPT_2                      matlab.ui.control.NumericEditField
        TotaltimeinAMhrLabel        matlab.ui.control.Label
        TTA_3                       matlab.ui.control.NumericEditField
        PostmachiningDetailsPanel   matlab.ui.container.Panel
        SetuptimeminLabel           matlab.ui.control.Label
        ST                          matlab.ui.control.NumericEditField
        MachiningtimeminLabel_2     matlab.ui.control.Label
        CT                          matlab.ui.control.NumericEditField
        EstimatetimeButton          matlab.ui.control.Button
        PartDetailsPanel            matlab.ui.container.Panel
        Volumeofthepartmm3Label     matlab.ui.control.Label
        PV                          matlab.ui.control.NumericEditField
        HeightofthepartmmLabel      matlab.ui.control.Label
        H                           matlab.ui.control.NumericEditField
        MassofthepartkgLabel        matlab.ui.control.Label
        MP                          matlab.ui.control.NumericEditField
        MassofAMfeaturekgLabel      matlab.ui.control.Label
        MP_AM                       matlab.ui.control.NumericEditField
        MassofSMfeaturekgLabel      matlab.ui.control.Label
        MP_SM                       matlab.ui.control.NumericEditField
        VolumeofAMfeaturemm3Label   matlab.ui.control.Label
        PV_AM                       matlab.ui.control.NumericEditField
        VolumeofSMfeaturemm3Label   matlab.ui.control.Label
        PV_SM                       matlab.ui.control.NumericEditField
        BuildtimeminLabel           matlab.ui.control.Label
        BT                          matlab.ui.control.NumericEditField
        EstimatedcostforAdditiveManufacturingPanel  matlab.ui.container.Panel
        MaterialcostLabel           matlab.ui.control.Label
        MC                          matlab.ui.control.NumericEditField
        LabourcostLabel             matlab.ui.control.Label
        LC                          matlab.ui.control.NumericEditField
        OperationcostLabel          matlab.ui.control.Label
        OC                          matlab.ui.control.NumericEditField
        TotalcostinAMLabel          matlab.ui.control.Label
        TCA                         matlab.ui.control.NumericEditField
        EstimatecostButton          matlab.ui.control.Button
        CNCMachiningTab             matlab.ui.container.Tab
        MachiningDetailsPanel       matlab.ui.container.Panel
        SetuptimeminLabel_2         matlab.ui.control.Label
        SET                         matlab.ui.control.NumericEditField
        MachiningtimeminLabel       matlab.ui.control.Label
        MT                          matlab.ui.control.NumericEditField
        ToolchangetimeminLabel      matlab.ui.control.Label
        TC                          matlab.ui.control.NumericEditField
        MassofthepartkgLabel_2      matlab.ui.control.Label
        MPS                         matlab.ui.control.NumericEditField
        ReorientationtimeminLabel   matlab.ui.control.Label
        RO                          matlab.ui.control.NumericEditField
        EstimatetimeButton_2        matlab.ui.control.Button
        EstimatecostButton_2        matlab.ui.control.Button
        EstimatedtimeforSubtractiveManufacturingPanel  matlab.ui.container.Panel
        TotaltimeinSMminLabel       matlab.ui.control.Label
        TTS                         matlab.ui.control.NumericEditField
        TotaltimeinSMhrLabel        matlab.ui.control.Label
        TTS_3                       matlab.ui.control.NumericEditField
        EstimatedcostforSubtractiveManufacturingPanel  matlab.ui.container.Panel
        MaterialcostLabel_2         matlab.ui.control.Label
        MC1                         matlab.ui.control.NumericEditField
        LabourcostLabel_2           matlab.ui.control.Label
        LC1                         matlab.ui.control.NumericEditField
        OperationcostLabel_2        matlab.ui.control.Label
        OC1                         matlab.ui.control.NumericEditField
        TotalcostinSMLabel          matlab.ui.control.Label
        TCS                         matlab.ui.control.NumericEditField
        HAMLMDCNCMachiningTab       matlab.ui.container.Tab
        EstimatedtimeandcostinCNCMachiningPanel  matlab.ui.container.Panel
        EstimatedtimeforSubtractiveManufacturingPanel_2  matlab.ui.container.Panel
        TotaltimeinSMminLabel_3     matlab.ui.control.Label
        TTS_2                       matlab.ui.control.NumericEditField
        EstimatedcostforSubtractiveManufacturingPanel_2  matlab.ui.container.Panel
        TotalcostinSMLabel_2        matlab.ui.control.Label
        TCS_2                       matlab.ui.control.NumericEditField
        EstimatedtimeandcostinCNCMachiningPanel_2  matlab.ui.container.Panel
        EstimatedtimeforSubtractiveManufacturingPanel_3  matlab.ui.container.Panel
        TotaltimeinSMminLabel_2     matlab.ui.control.Label
        TTA_2                       matlab.ui.control.NumericEditField
        EstimatedcostforSubtractiveManufacturingPanel_3  matlab.ui.container.Panel
        TotalcostinSMLabel_3        matlab.ui.control.Label
        TCA_2                       matlab.ui.control.NumericEditField
        EstimatedtimeforHybridAdditiveManufacturingPanel  matlab.ui.container.Panel
        TotaltimeinHAMminLabel      matlab.ui.control.Label
        TTH                         matlab.ui.control.NumericEditField
        EstimatedcostforHybridAdditiveManufacturingPanel  matlab.ui.container.Panel
        TotalcostinHAMLabel         matlab.ui.control.Label
        TCH                         matlab.ui.control.NumericEditField
        EstimatetimeButton_3        matlab.ui.control.Button
        EstimatecostButton_3        matlab.ui.control.Button
        CostingcomparisoninAMSMTab  matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        PlotbarchartButton          matlab.ui.control.StateButton
        UIAxes_2                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PV
        function PVValueChanged(app, event)
            value = app.PV.Value; %% Part volume
            
        end

        % Button pushed function: EstimatetimeButton
        function EstimatetimeButtonPushed(app, event)
             
                        
            % Pre-processing time
            pl = 3; %% powder loading time
            sc = 2; %% STL file correction
            is = 10; %% Initial setting
            app.PRPT.Value = pl+sc+is  %% Total pre-processing time
            app.PRPT_2.Value = app.PRPT.Value/60;
            
            % processing time
%             hs = 1.5;
%             tld = 0.0008333;
%             tjd = 0.0008333;
%             vs = 1000;
%             vj = 40000;
%             lt = 0.8
%             rho = app.PV.Value/app.BBV.Value;
%             cf = rho*exp(1.5*(1-rho));
%             s = (app.BBV.Value*cf)/app.H.Value;
%             td = (app.W.Value*((4*tld)+tjd))/hs;
%             v = (vs*cf)+(vj*(1-cf));
%             t = (s/(hs*v))+td;
%             app.PT.Value = (t*app.H.Value)/lt

            %% Deposition time
%             dfd =  %% Deposition feed distance
%             df =  %% Deposition feed
%             t_dep = (dfd/df); 
            
             %% Rapid time
%             rfd = %% Rapid feed distance
%             rf =  %% Rapid feed
%             t_rap = (rfd/rf);
            
%             app.PT.Value = t_dep + t_rap; 
            app.PT.Value = app.BT.Value; %% Processing time from NX software
            app.PT_2.Value = app.PT.Value/60; %% Processing time in 'hr'
            
            % Post-processing time
            pr = 15; %% Part removal time
            pm = app.ST.Value + app.CT.Value; %% Post machining time
            app.POPT.Value = pr+pm; %% Post-processing time in 'min'
            app.POPT_2.Value = app.POPT.Value/60; %% Post-processing time in 'hr'
            
            % Total time in AM
            app.TTA.Value = app.PRPT.Value + app.PT.Value + app.POPT.Value;
            app.TTA_3.Value = app.TTA.Value/60;        


        end

        % Button pushed function: EstimatecostButton
        function EstimatecostButtonPushed(app, event)
            % Material cost
            ce = 0.7 %% Catchment efficiency with range 70-85% 
            cm = 33.85; %% Unit material cost
            cg = 0.0000513; %% Unit process gas cost
            ms_1 = ((app.MP_AM.Value*0.15) + app.MP_AM.Value)*(1+(1-ce))*cm; %% Mass of the part with stock in AM
            ms_2 = ((app.PV_AM.Value + (0.15*app.PV_AM.Value))*cg); %% Process gas consumption
            app.MC.Value = ms_1 + ms_2;
            
            % Labour cost
            tl = app.PRPT.Value + app.POPT.Value +(0.1*app.PT.Value); %% Labour time
            cl = 25; %% Labour hourly rate
            app.LC.Value = (cl*(tl/60));
            
            % Operation cost
            coa = 30; %% AM hourly rate
            cos = 30; %% SM hourly rate
            coedm = 30; %% EDM hourly rate
            pl=3; %% Powder loading time
            is=10; %% Initial setting time
            pm = app.ST.Value + app.CT.Value;
            pr=15; %% Part removal
            app.OC.Value = (coa*((pl+is+app.PT.Value)/60)) +...
            (cos*(pm/60)) + (coedm*(pr/60));
            
            % Total cost
            app.TCA.Value = app.MC.Value + app.LC.Value + app.OC.Value;          
              
            
        end

        % Callback function
        function ImageClicked(app, event)
        f = uifigure;
        im = uiimage(f);
        im.ImageSource = 'LMD.jpg';
        
        end

        % Button pushed function: EstimatetimeButton_2
        function EstimatetimeButton_2Pushed(app, event)
            app.TTS.Value = app.SET.Value + app.MT.Value +...
                app.TC.Value + app.RO.Value;
            app.TTS_3.Value = app.TTS.Value/60;
        end

        % Button pushed function: EstimatecostButton_2
        function EstimatecostButton_2Pushed(app, event)
        
        % Material cost
        cmach = 22; %% Mass of the part in SM
        app.MC1.Value = cmach*app.MPS.Value;
        
        % Labour cost
        cls = 25; %% Labour hour rate
        app.LC1.Value = cls*(app.TTS.Value/60);
        
        % Operation cost
        cor = 30; %% Operation hour rate
        app.OC1.Value = cor*(app.TTS.Value/60);
        
        % Total cost
        app.TCS.Value = app.MC1.Value + app.LC1.Value + app.OC1.Value;
        end

        % Button pushed function: EstimatetimeButton_3
        function EstimatetimeButton_3Pushed(app, event)
            % Total time in HAM
            app.TTS_2.Value = app.TTS.Value;
            app.TTA_2.Value = app.TTA.Value;
            app.TTH.Value = app.TTS_2.Value + app.TTA_2.Value;
            
            
        end

        % Button pushed function: EstimatecostButton_3
        function EstimatecostButton_3Pushed(app, event)
             % Total cost in HAM
            app.TCS_2.Value = app.TCS.Value;
            app.TCA_2.Value = app.TCA.Value;
            app.TCH.Value = app.TCS_2.Value + app.TCA_2.Value;
            
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            % Additive Manufacturing
            app.BFA.Value = ((app.PV.Value*0.30)+app.PV.Value)/app.PV.Value
            app.MEA.Value = 100/app.BFA.Value
            app.WA.Value = ((((app.PV.Value*0.30)+app.PV.Value) - app.PV.Value)*100)/((app.PV.Value*0.3)+app.PV.Value)
            
            
            % Subtractive Manufacturing
            app.BFS.Value = app.SVS.Value / app.PV.Value
            app.MES.Value = 100/app.BFS.Value
            app.WS.Value = ((app.SVS.Value - app.PV.Value)*100)/app.SVS.Value
            
           % Plot graph between buy to fly ratio and material cost
            x = 10:100;
                       
            y(:,1) = (x-x) + (1.15*app.PV.Value*33.85*0.000008);


            y(:,2) = (x*app.PV.Value*22*0.000008)/10;
            
            plot(app.GRA, x, y(:,1), ....
                x,y(:,2));
            legend(app.GRA,{'AM','SM'},'Location','Best')
            xlim (app.GRA, [0 100]);
            ylim (app.GRA, [0 100]);
            
            % To find optimum material ratio and material cost in SS316L
            x(1) = 0;
            y(1) = 1.15*app.PV.Value*33.85*0.000008;
            
            
            x(2) = 10;
            y(2) = 1.15*app.PV.Value*33.85*0.000008;
            
            
            x(3) = 0;
            x(4) = 10;
            y(3) = x(4)*app.PV.Value*22*0.000008;                     
            y(4) = x(3)*app.PV.Value*22*0.000008;
            
            
             denominator = (x(1)-x(2))*(y(3)-y(4))-(y(1)-y(2))*(x(3)-x(4));
            
            app.OMC.Value = ((x(1)*y(2)-y(1)*x(2))*(y(3)-y(4))-(y(1)-y(2))*(x(3)*y(4)-y(3)*x(4)))/denominator
            
            app.OBFRA.Value = 100 - ((((x(1)*y(2)-y(1)*x(2))*(x(3)-x(4))-(x(1)-x(2))*(x(3)*y(4)-y(3)*x(4)))/denominator)*10)

%             app.OBFRS.Value = (100 - app.OBFRA.Value)


        end

        % Callback function
        function SliderValueChanged(app, event)


        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
           % Additive Manufacturing
            app.BFA_2.Value = ((app.PV.Value*0.3)+app.PV.Value)/app.PV.Value
            app.MEA_2.Value = 100/app.BFA_2.Value
            app.WA_2.Value = ((((app.PV.Value*0.3)+app.PV.Value) - app.PV.Value)*100)/((app.PV.Value*0.3)+app.PV.Value)
            
            
            % Subtractive Manufacturing
            app.BFS_2.Value = app.SVS.Value / app.PV.Value
            app.MES_2.Value = 100/app.BFS_2.Value
            app.WS_2.Value = ((app.SVS.Value - app.PV.Value)*100)/app.SVS.Value
            
           % Plot graph between buy to fly ratio and material cost
            x = 10:100;
                       
            y(:,1) = (x-x) + (1.15*app.PV.Value*150*0.000008);


            y(:,2) = (x*app.PV.Value*110*0.000008)/10;
            
            plot(app.GRA_2, x, y(:,1), ....
               x, y(:,2));
            legend(app.GRA_2,{'AM','SM'},'Location','Best')
            xlim (app.GRA_2, [0 100]);
            ylim (app.GRA_2, [0 350]);
            
            % To find optimum material ratio and material cost in Ti6Al4V
            m(1) = 0;
            n(1) = 1.15*app.PV.Value*150*0.000008;
            
            
            m(2) = 10;
            n(2) = 1.15*app.PV.Value*150*0.000008;
            
            
            m(3) = 0;
            m(4) = 10;
            n(3) = m(4)*app.PV.Value*110*0.000008;                     
            n(4) = m(3)*app.PV.Value*110*0.000008;
            
            
             deno = (m(1)-m(2))*(n(3)-n(4))-(n(1)-n(2))*(m(3)-m(4));
            
            app.OMC_2.Value = ((m(1)*n(2)-n(1)*m(2))*(n(3)-n(4))-(n(1)-n(2))*(m(3)*n(4)-n(3)*m(4)))/deno
            
            app.OBFRA_2.Value = 100 - ((((m(1)*n(2)-n(1)*m(2))*(m(3)-m(4))-(m(1)-m(2))*(m(3)*n(4)-n(3)*m(4)))/deno) * 10)

%             app.OBFRS_2.Value = (10 - app.OBFRA_2.Value)
            
%             x_1(1) = 0;
%             y_1(1) = x_1(1)*app.PV.Value*150*0.000008;
%             
%             
%             x_1(2) = 10;
%             y_1(2) = x_1(2)*app.PV.Value*150*0.000008;
%             
%             
%             x_1(3) = 0;
%             x_1(4) = 10;
%             y_1(3) = x_1(4)*app.PV.Value*110*0.000008;                     
%             y_1(4) = x_1(3)*app.PV.Value*110*0.000008;
%             
%             
%              denominator_1 = (x_1(1)-x_1(2))*(y_1(3)-y_1(4))-(y_1(1)-y_1(2))*(x_1(3)-x_1(4));
%             
%             app.OMC_2.Value = ((x_1(1)*y_1(2)-y_1(1)*x_1(2))*(y_1(3)-y_1(4))-(y_1(1)-y_1(2))*(x_1(3)*y_1(4)-y_1(3)*x_1(4)))/denominator_1;
%             
%             app.OBFRA_2.Value = ((x_1(1)*y_1(2)-y_1(1)*x_1(2))*(x_1(3)-x_1(4))-(x_1(1)-x_1(2))*(x_1(3)*y_1(4)-y_1(3)*x_1(4)))/denominator_1;
% 
%             app.OBFRS_2.Value = (10 - app.OBFRA_2.Value);
%             
             
        end

        % Callback function
        function OPAMValueChanged(app, event)
                       
            
        end

        % Callback function
        function LAAMValueChanged(app, event)
            
        end

        % Callback function
        function MAAMValueChanged(app, event)
            
        end

        % Callback function
        function TCAMValueChanged(app, event)
            
        end

        % Callback function
        function CalculateButton_3Pushed(app, event)


        end

        % Callback function
        function OPAMValueChanged2(app, event)
           
        end

        % Callback function
        function LAAMValueChanged2(app, event)
            
        end

        % Callback function
        function MAAMValueChanged2(app, event)
            
        end

        % Callback function
        function TCAMValueChanged2(app, event)
            
        end

        % Value changed function: PlotbarchartButton
        function PlotbarchartButtonValueChanged(app, event)
            value = app.PlotbarchartButton.Value;
            y = [app.OC.Value app.LC.Value app.MC.Value]
            bar(app.UIAxes, y)
           
            y = [app.OC1.Value app.LC1.Value app.MC1.Value]
            bar(app.UIAxes_2, y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cost_Estimation = uifigure('Visible', 'off');
            app.Cost_Estimation.Name = 'UI Figure';
            app.CostEstimationinHybridAdditiveManufacturingHAMLabel = uilabel(app.Cost_Estimation);
            app.CostEstimationinHybridAdditiveManufacturingHAMLabel.Text = 'Cost Estimation in Hybrid Additive Manufacturing (HAM) ';
            app.TabGroup = uitabgroup(app.Cost_Estimation);
            app.LMDTab = uitab(app.TabGroup);
            app.LMDTab.Title = 'LMD ';
            app.EstimatedtimeforAdditiveManufacturingPanel = uipanel(app.LMDTab);
            app.EstimatedtimeforAdditiveManufacturingPanel.Title = 'Estimated time for Additive Manufacturing';
            app.PreprocesssingtimeminLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.PreprocesssingtimeminLabel.Text = 'Pre-processsing time (min)';
            app.PRPT = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.ProcessingtimeminLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.ProcessingtimeminLabel.Text = 'Processing time (min)';
            app.PT = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.PostprocessingtimeminLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.PostprocessingtimeminLabel.Text = 'Post-processing time (min)';
            app.POPT = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.TotaltimeinAMminLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.TotaltimeinAMminLabel.Text = 'Total time in AM (min)';
            app.TTA = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.PreprocesssingtimehrLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.PreprocesssingtimehrLabel.Text = 'Pre-processsing time (hr)';
            app.PRPT_2 = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.ProcessingtimehrLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.ProcessingtimehrLabel.Text = 'Processing time (hr';
            app.PT_2 = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.PostprocessingtimehrLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.PostprocessingtimehrLabel.Text = 'Post-processing time (hr)';
            app.POPT_2 = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.TotaltimeinAMhrLabel = uilabel(app.EstimatedtimeforAdditiveManufacturingPanel);
            app.TotaltimeinAMhrLabel.Text = 'Total time in AM (hr)';
            app.TTA_3 = uieditfield(app.EstimatedtimeforAdditiveManufacturingPanel, 'numeric');
            app.PostmachiningDetailsPanel = uipanel(app.LMDTab);
            app.PostmachiningDetailsPanel.Title = 'Post-machining Details';
            app.SetuptimeminLabel = uilabel(app.PostmachiningDetailsPanel);
            app.SetuptimeminLabel.Text = 'Set-up time (min)';
            app.ST = uieditfield(app.PostmachiningDetailsPanel, 'numeric');
            app.ST.Value = 5;
            app.MachiningtimeminLabel_2 = uilabel(app.PostmachiningDetailsPanel);
            app.MachiningtimeminLabel_2.Text = 'Machining time (min)';
            app.CT = uieditfield(app.PostmachiningDetailsPanel, 'numeric');
            app.CT.Value = 15;
            app.EstimatetimeButton = uibutton(app.LMDTab, 'push');
            app.EstimatetimeButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatetimeButtonPushed, true);
            app.EstimatetimeButton.Text = 'Estimate time';
            app.PartDetailsPanel = uipanel(app.LMDTab);
            app.PartDetailsPanel.Title = 'Part Details';
            app.Volumeofthepartmm3Label = uilabel(app.PartDetailsPanel);
            app.Volumeofthepartmm3Label.Text = 'Volume of the part (mm^3)';
            app.PV = uieditfield(app.PartDetailsPanel, 'numeric');
            app.PV.ValueDisplayFormat = '%.0f';
            app.PV.ValueChangedFcn = createCallbackFcn(app, @PVValueChanged, true);
            app.PV.Value = 374800;
            app.HeightofthepartmmLabel = uilabel(app.PartDetailsPanel);
            app.HeightofthepartmmLabel.Text = 'Height of the part (mm)';
            app.H = uieditfield(app.PartDetailsPanel, 'numeric');
            app.H.Value = 262;
            app.MassofthepartkgLabel = uilabel(app.PartDetailsPanel);
            app.MassofthepartkgLabel.Text = {'Mass of the part (kg)'; ''};
            app.MP = uieditfield(app.PartDetailsPanel, 'numeric');
            app.MP.Value = 2.941;
            app.MassofAMfeaturekgLabel = uilabel(app.PartDetailsPanel);
            app.MassofAMfeaturekgLabel.Text = {'Mass of AM feature (kg)'; ''};
            app.MP_AM = uieditfield(app.PartDetailsPanel, 'numeric');
            app.MP_AM.Value = 0.814;
            app.MassofSMfeaturekgLabel = uilabel(app.PartDetailsPanel);
            app.MassofSMfeaturekgLabel.Text = {'Mass of SM feature (kg)'; ''};
            app.MP_SM = uieditfield(app.PartDetailsPanel, 'numeric');
            app.MP_SM.Value = 12.87;
            app.VolumeofAMfeaturemm3Label = uilabel(app.PartDetailsPanel);
            app.VolumeofAMfeaturemm3Label.Text = {'Volume of AM feature (mm^3)'; ''};
            app.PV_AM = uieditfield(app.PartDetailsPanel, 'numeric');
            app.PV_AM.RoundFractionalValues = 'on';
            app.PV_AM.ValueDisplayFormat = '%.0f';
            app.PV_AM.Value = 103700;
            app.VolumeofSMfeaturemm3Label = uilabel(app.PartDetailsPanel);
            app.VolumeofSMfeaturemm3Label.Text = {'Volume of SM feature (mm^3)'; ''};
            app.PV_SM = uieditfield(app.PartDetailsPanel, 'numeric');
            app.PV_SM.RoundFractionalValues = 'on';
            app.PV_SM.ValueDisplayFormat = '%.0f';
            app.PV_SM.Value = 1640000;
            app.BuildtimeminLabel = uilabel(app.PartDetailsPanel);
            app.BuildtimeminLabel.Text = 'Build time (min)';
            app.BT = uieditfield(app.PartDetailsPanel, 'numeric');
            app.BT.RoundFractionalValues = 'on';
            app.BT.ValueDisplayFormat = '%.0f';
            app.BT.Value = 58;
            app.EstimatedcostforAdditiveManufacturingPanel = uipanel(app.LMDTab);
            app.EstimatedcostforAdditiveManufacturingPanel.Title = 'Estimated cost for Additive Manufacturing';
            app.MaterialcostLabel = uilabel(app.EstimatedcostforAdditiveManufacturingPanel);
            app.MaterialcostLabel.Text = 'Material cost ($)';
            app.MC = uieditfield(app.EstimatedcostforAdditiveManufacturingPanel, 'numeric');
            app.LabourcostLabel = uilabel(app.EstimatedcostforAdditiveManufacturingPanel);
            app.LabourcostLabel.Text = 'Labour cost ($)';
            app.LC = uieditfield(app.EstimatedcostforAdditiveManufacturingPanel, 'numeric');
            app.OperationcostLabel = uilabel(app.EstimatedcostforAdditiveManufacturingPanel);
            app.OperationcostLabel.Text = 'Operation cost ($)';
            app.OC = uieditfield(app.EstimatedcostforAdditiveManufacturingPanel, 'numeric');
            app.TotalcostinAMLabel = uilabel(app.EstimatedcostforAdditiveManufacturingPanel);
            app.TotalcostinAMLabel.Text = 'Total cost in AM ($)';
            app.TCA = uieditfield(app.EstimatedcostforAdditiveManufacturingPanel, 'numeric');
            app.EstimatecostButton = uibutton(app.LMDTab, 'push');
            app.EstimatecostButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatecostButtonPushed, true);
            app.EstimatecostButton.Text = 'Estimate cost';
            app.CNCMachiningTab = uitab(app.TabGroup);
            app.CNCMachiningTab.Title = 'CNC Machining';
            app.MachiningDetailsPanel = uipanel(app.CNCMachiningTab);
            app.MachiningDetailsPanel.Title = 'Machining Details';
            app.SetuptimeminLabel_2 = uilabel(app.MachiningDetailsPanel);
            app.SetuptimeminLabel_2.Text = 'Set-up time (min)';
            app.SET = uieditfield(app.MachiningDetailsPanel, 'numeric');
            app.SET.Value = 30;
            app.MachiningtimeminLabel = uilabel(app.MachiningDetailsPanel);
            app.MachiningtimeminLabel.Text = 'Machining time (min)';
            app.MT = uieditfield(app.MachiningDetailsPanel, 'numeric');
            app.MT.Value = 217.8;
            app.ToolchangetimeminLabel = uilabel(app.MachiningDetailsPanel);
            app.ToolchangetimeminLabel.Text = 'Tool change time (min)';
            app.TC = uieditfield(app.MachiningDetailsPanel, 'numeric');
            app.TC.Value = 5;
            app.MassofthepartkgLabel_2 = uilabel(app.MachiningDetailsPanel);
            app.MassofthepartkgLabel_2.Text = 'Mass of the part (kg)';
            app.MPS = uieditfield(app.MachiningDetailsPanel, 'numeric');
            app.MPS.Value = 12.87;
            app.ReorientationtimeminLabel = uilabel(app.MachiningDetailsPanel);
            app.ReorientationtimeminLabel.Text = 'Reorientation time (min)';
            app.RO = uieditfield(app.MachiningDetailsPanel, 'numeric');
            app.RO.Value = 5;
            app.EstimatetimeButton_2 = uibutton(app.CNCMachiningTab, 'push');
            app.EstimatetimeButton_2.ButtonPushedFcn = createCallbackFcn(app, @EstimatetimeButton_2Pushed, true);
            app.EstimatetimeButton_2.Text = 'Estimate time';
            app.EstimatecostButton_2 = uibutton(app.CNCMachiningTab, 'push');
            app.EstimatecostButton_2.ButtonPushedFcn = createCallbackFcn(app, @EstimatecostButton_2Pushed, true);
            app.EstimatecostButton_2.Text = 'Estimate cost';
            app.EstimatedtimeforSubtractiveManufacturingPanel = uipanel(app.CNCMachiningTab);
            app.EstimatedtimeforSubtractiveManufacturingPanel.Title = 'Estimated time for Subtractive Manufacturing';
            app.TotaltimeinSMminLabel = uilabel(app.EstimatedtimeforSubtractiveManufacturingPanel);
            app.TotaltimeinSMminLabel.Text = 'Total time in SM (min)';
            app.TTS = uieditfield(app.EstimatedtimeforSubtractiveManufacturingPanel, 'numeric');
            app.TotaltimeinSMhrLabel = uilabel(app.EstimatedtimeforSubtractiveManufacturingPanel);
            app.TotaltimeinSMhrLabel.Text = 'Total time in SM (hr)';
            app.TTS_3 = uieditfield(app.EstimatedtimeforSubtractiveManufacturingPanel, 'numeric');
            app.EstimatedcostforSubtractiveManufacturingPanel = uipanel(app.CNCMachiningTab);
            app.EstimatedcostforSubtractiveManufacturingPanel.Title = 'Estimated cost for Subtractive Manufacturing';
            app.MaterialcostLabel_2 = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel);
            app.MaterialcostLabel_2.Text = 'Material cost ($)';
            app.MC1 = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel, 'numeric');
            app.LabourcostLabel_2 = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel);
            app.LabourcostLabel_2.Text = 'Labour cost ($)';
            app.LC1 = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel, 'numeric');
            app.OperationcostLabel_2 = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel);
            app.OperationcostLabel_2.Text = 'Operation cost ($)';
            app.OC1 = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel, 'numeric');
            app.TotalcostinSMLabel = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel);
            app.TotalcostinSMLabel.Text = 'Total cost in SM ($)';
            app.TCS = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel, 'numeric');
            app.HAMLMDCNCMachiningTab = uitab(app.TabGroup);
            app.HAMLMDCNCMachiningTab.Title = 'HAM (LMD+CNC Machining)';
            app.EstimatedtimeandcostinCNCMachiningPanel = uipanel(app.HAMLMDCNCMachiningTab);
            app.EstimatedtimeandcostinCNCMachiningPanel.Title = 'Estimated time and cost in CNC Machining';
            app.EstimatedtimeforSubtractiveManufacturingPanel_2 = uipanel(app.EstimatedtimeandcostinCNCMachiningPanel);
            app.EstimatedtimeforSubtractiveManufacturingPanel_2.Title = 'Estimated time for Subtractive Manufacturing';
            app.TotaltimeinSMminLabel_3 = uilabel(app.EstimatedtimeforSubtractiveManufacturingPanel_2);
            app.TotaltimeinSMminLabel_3.Text = 'Total time in SM (min)';
            app.TTS_2 = uieditfield(app.EstimatedtimeforSubtractiveManufacturingPanel_2, 'numeric');
            app.EstimatedcostforSubtractiveManufacturingPanel_2 = uipanel(app.EstimatedtimeandcostinCNCMachiningPanel);
            app.EstimatedcostforSubtractiveManufacturingPanel_2.Title = 'Estimated cost for Subtractive Manufacturing';
            app.TotalcostinSMLabel_2 = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel_2);
            app.TotalcostinSMLabel_2.Text = 'Total cost in SM ($)';
            app.TCS_2 = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel_2, 'numeric');
            app.EstimatedtimeandcostinCNCMachiningPanel_2 = uipanel(app.HAMLMDCNCMachiningTab);
            app.EstimatedtimeandcostinCNCMachiningPanel_2.Title = 'Estimated time and cost in CNC Machining';
            app.EstimatedtimeforSubtractiveManufacturingPanel_3 = uipanel(app.EstimatedtimeandcostinCNCMachiningPanel_2);
            app.EstimatedtimeforSubtractiveManufacturingPanel_3.Title = 'Estimated time for Subtractive Manufacturing';
            app.TotaltimeinSMminLabel_2 = uilabel(app.EstimatedtimeforSubtractiveManufacturingPanel_3);
            app.TotaltimeinSMminLabel_2.Text = 'Total time in SM (min)';
            app.TTA_2 = uieditfield(app.EstimatedtimeforSubtractiveManufacturingPanel_3, 'numeric');
            app.EstimatedcostforSubtractiveManufacturingPanel_3 = uipanel(app.EstimatedtimeandcostinCNCMachiningPanel_2);
            app.EstimatedcostforSubtractiveManufacturingPanel_3.Title = 'Estimated cost for Subtractive Manufacturing';
            app.TotalcostinSMLabel_3 = uilabel(app.EstimatedcostforSubtractiveManufacturingPanel_3);
            app.TotalcostinSMLabel_3.Text = 'Total cost in SM ($)';
            app.TCA_2 = uieditfield(app.EstimatedcostforSubtractiveManufacturingPanel_3, 'numeric');
            app.EstimatedtimeforHybridAdditiveManufacturingPanel = uipanel(app.HAMLMDCNCMachiningTab);
            app.EstimatedtimeforHybridAdditiveManufacturingPanel.Title = 'Estimated time for Hybrid Additive Manufacturing';
            app.TotaltimeinHAMminLabel = uilabel(app.EstimatedtimeforHybridAdditiveManufacturingPanel);
            app.TotaltimeinHAMminLabel.Text = 'Total time in HAM (min)';
            app.TTH = uieditfield(app.EstimatedtimeforHybridAdditiveManufacturingPanel, 'numeric');
            app.EstimatedcostforHybridAdditiveManufacturingPanel = uipanel(app.HAMLMDCNCMachiningTab);
            app.EstimatedcostforHybridAdditiveManufacturingPanel.Title = 'Estimated cost for Hybrid Additive Manufacturing';
            app.TotalcostinHAMLabel = uilabel(app.EstimatedcostforHybridAdditiveManufacturingPanel);
            app.TotalcostinHAMLabel.Text = 'Total cost in HAM ($)';
            app.TCH = uieditfield(app.EstimatedcostforHybridAdditiveManufacturingPanel, 'numeric');
            app.EstimatetimeButton_3 = uibutton(app.HAMLMDCNCMachiningTab, 'push');
            app.EstimatetimeButton_3.ButtonPushedFcn = createCallbackFcn(app, @EstimatetimeButton_3Pushed, true);
            app.EstimatetimeButton_3.Text = 'Estimate time';
            app.EstimatecostButton_3 = uibutton(app.HAMLMDCNCMachiningTab, 'push');
            app.EstimatecostButton_3.ButtonPushedFcn = createCallbackFcn(app, @EstimatecostButton_3Pushed, true);
            app.EstimatecostButton_3.Text = 'Estimate cost';
            app.CostingcomparisoninAMSMTab = uitab(app.TabGroup);
            app.CostingcomparisoninAMSMTab.Title = 'Costing comparison in AM & SM';
            app.UIAxes = uiaxes(app.CostingcomparisoninAMSMTab);
            title(app.UIAxes, 'Comparison of costing in AM')
            xlabel(app.UIAxes, 'Costing')
            ylabel(app.UIAxes, 'Cost ($)')
            app.UIAxes.XTickLabel = {'OC'; 'LC'; 'MC'};
            app.PlotbarchartButton = uibutton(app.CostingcomparisoninAMSMTab, 'state');
            app.PlotbarchartButton.ValueChangedFcn = createCallbackFcn(app, @PlotbarchartButtonValueChanged, true);
            app.PlotbarchartButton.Text = 'Plot bar chart';
            app.UIAxes_2 = uiaxes(app.CostingcomparisoninAMSMTab);
            title(app.UIAxes_2, 'Comparison of costing in SM')
            xlabel(app.UIAxes_2, 'Costing')
            ylabel(app.UIAxes_2, 'Cost ($)')
            app.UIAxes_2.XTickLabel = {'OC'; 'LC'; 'MC'};
            % Show the figure after all components are created
            app.Cost_Estimation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cost_estimation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cost_Estimation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cost_Estimation)
        end
    end
end